classdef Flight_DestinationDate < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BesminAirlinesUIFigure        matlab.ui.Figure
        Image2_2                      matlab.ui.control.Image
        Image5                        matlab.ui.control.Image
        Image2                        matlab.ui.control.Image
        PlaceOfDestination            matlab.ui.control.Label
        PlaceOfOrigin                 matlab.ui.control.Label
        NextButton                    matlab.ui.control.Button
        ReturnDateDatePicker          matlab.ui.control.DatePicker
        ReturnDateDatePickerLabel     matlab.ui.control.Label
        DepartureDateDatePicker       matlab.ui.control.DatePicker
        DepartureDateDatePickerLabel  matlab.ui.control.Label
        Image                         matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            if isnat(app.DepartureDateDatePicker.Value) || isnat(app.ReturnDateDatePicker.Value)
                uialert(app.BesminAirlinesUIFigure,'Please fill in all fields before proceeding','Note');
            else
                departureDate = app.DepartureDateDatePicker.Value;
                returnDate = app.ReturnDateDatePicker.Value;

                data = {departureDate, returnDate};
                flightData = cell2table(data, 'VariableNames', {'Departure Date', 'Return Date'});
                writetable(flightData, 'flightDetails.xlsx');

                delete(app.BesminAirlinesUIFigure);
                Flight_PassengerDeets;
            end
        end

        % Image clicked function: Image5
        function Go_To_Profile(app, event)
            Flight_Profile;
            delete(app.BesminAirlinesUIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.BesminAirlinesUIFigure = uifigure('Visible', 'off');
            app.BesminAirlinesUIFigure.Name = 'Besmin Airlines';
            app.BesminAirlinesUIFigure.Icon = fullfile(pathToMLAPP, 'Est._2024-removebg-preview.png');
            app.Image = uiimage(app.BesminAirlinesUIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Flight Dates.png');
            app.DepartureDateDatePickerLabel = uilabel(app.BesminAirlinesUIFigure);
            app.DepartureDateDatePickerLabel.Text = 'Departure Date';
            app.DepartureDateDatePicker = uidatepicker(app.BesminAirlinesUIFigure);
            app.DepartureDateDatePicker.Limits = [datetime([2024 1 1]) datetime([9999 12 31])];
            app.ReturnDateDatePickerLabel = uilabel(app.BesminAirlinesUIFigure);
            app.ReturnDateDatePickerLabel.Text = 'Return Date';
            app.ReturnDateDatePicker = uidatepicker(app.BesminAirlinesUIFigure);
            app.ReturnDateDatePicker.Limits = [datetime([2024 1 1]) datetime([9999 12 31])];
            app.NextButton = uibutton(app.BesminAirlinesUIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.PlaceOfOrigin = uilabel(app.BesminAirlinesUIFigure);
            app.PlaceOfOrigin.Text = '';
            app.PlaceOfDestination = uilabel(app.BesminAirlinesUIFigure);
            app.PlaceOfDestination.Text = '';
            app.Image2 = uiimage(app.BesminAirlinesUIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, '88b9cea9f2d6fc48920f86cf8033620e.jpg');
            app.Image5 = uiimage(app.BesminAirlinesUIFigure);
            app.Image5.ImageClickedFcn = createCallbackFcn(app, @Go_To_Profile, true);
            app.Image5.ImageSource = fullfile(pathToMLAPP, '5700c04197ee9a4372a35ef16eb78f4e-removebg-preview.png');
            app.Image2_2 = uiimage(app.BesminAirlinesUIFigure);
            app.Image2_2.ImageSource = fullfile(pathToMLAPP, 'Est._2024-removebg-preview (1).png');
            % Show the figure after all components are created
            app.BesminAirlinesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Flight_DestinationDate

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BesminAirlinesUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BesminAirlinesUIFigure)
        end
    end
end