classdef Flight_Emergency < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Image5                        matlab.ui.control.Image
        Image2_2                      matlab.ui.control.Image
        ADDRESSEditField              matlab.ui.control.EditField
        ADDRESSLabel                  matlab.ui.control.Label
        RELATIONSHIPEditField         matlab.ui.control.EditField
        RELATIONSHIPEditFieldLabel    matlab.ui.control.Label
        BIRTHDAYDatePicker            matlab.ui.control.DatePicker
        BIRTHDAYLabel                 matlab.ui.control.Label
        NOEditField                   matlab.ui.control.EditField
        NOLabel                       matlab.ui.control.Label
        EMAILEditField                matlab.ui.control.EditField
        EMAILLabel                    matlab.ui.control.Label
        NAMEEditField                 matlab.ui.control.EditField
        NAMELabel                     matlab.ui.control.Label
        SUBMITButton                  matlab.ui.control.Button
        INCASEOFEMERGENCYPLEASELabel  matlab.ui.control.Label
        EMERGENCYCONTACTLabel         matlab.ui.control.Label
        Image3                        matlab.ui.control.Image
        Image2                        matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SUBMITButton
        function SUBMITButtonPushed(app, event)
            % Collect data from UI components and ensure proper data types
            data.name = string(app.NAMEEditField.Value); 
            data.email = string(app.EMAILEditField.Value);
            data.no = string(app.NOEditField.Value); % Convert to string
            data.birthday = string(datestr(app.BIRTHDAYDatePicker.Value, 'mm/dd/yyyy')); % Ensure correct date format as string
            data.relationship = string(app.RELATIONSHIPEditField.Value);
            data.address = string(app.ADDRESSEditField.Value);
            
            % Create a cell array with the data
            dataCell = {data.name, data.email, data.no, data.birthday, data.relationship, data.address};
            
            % Create a table with the data
            newEntry = cell2table(dataCell, 'VariableNames', {'ECName', 'ECEmail', 'ECNumber', 'ECDOB', 'ECRelationship', 'ECAddress'});
            
            % Define the file path for the Excel file
            filename = 'FlightEmergency.xlsx';
            
            % Check if the file exists
            if isfile(filename)
                % If file exists, append the data
                try
                    % Read the existing data
                    existingData = readtable(filename, 'Sheet', 1);
                    % Append the new entry
                    updatedData = [existingData; newEntry];
                    % Write the updated data back to the file
                    writetable(updatedData, filename, 'Sheet', 1, 'WriteRowNames', false);
                catch ME
                    uialert(app.UIFigure, ['An error occurred while updating the file: ', ME.message], 'Error');
                end
            else
                % If file does not exist, create it and write the data
                try
                    writetable(newEntry, filename, 'Sheet', 1, 'WriteRowNames', false);
                catch ME
                    uialert(app.UIFigure, ['An error occurred while creating the file: ', ME.message], 'Error');
                end
            end
            
            % Notify the user
            uialert(app.UIFigure, 'Data saved successfully!', 'Success');
            Flight_TicketPage;
            delete(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'b9736d91806e3a2b8db1ab3e6a7bb0b0 (1).jpg');
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, '702e40edcf6d527a7cb598ba2204d6be.jpg');
            app.EMERGENCYCONTACTLabel = uilabel(app.UIFigure);
            app.EMERGENCYCONTACTLabel.Text = 'EMERGENCY CONTACT';
            app.INCASEOFEMERGENCYPLEASELabel = uilabel(app.UIFigure);
            app.INCASEOFEMERGENCYPLEASELabel.Text = '! INCASE OF EMERGENCY PLEASE PUT THE INFORMATION OF YOUR EMERGENCY CONTACT PERSON';
            app.SUBMITButton = uibutton(app.UIFigure, 'push');
            app.SUBMITButton.ButtonPushedFcn = createCallbackFcn(app, @SUBMITButtonPushed, true);
            app.SUBMITButton.Text = 'SUBMIT';
            app.NAMELabel = uilabel(app.UIFigure);
            app.NAMELabel.Text = 'NAME :';
            app.NAMEEditField = uieditfield(app.UIFigure, 'text');
            app.EMAILLabel = uilabel(app.UIFigure);
            app.EMAILLabel.Text = 'EMAIL :';
            app.EMAILEditField = uieditfield(app.UIFigure, 'text');
            app.NOLabel = uilabel(app.UIFigure);
            app.NOLabel.Text = 'NO.:';
            app.NOEditField = uieditfield(app.UIFigure, 'text');
            app.BIRTHDAYLabel = uilabel(app.UIFigure);
            app.BIRTHDAYLabel.Text = 'BIRTHDAY :';
            app.BIRTHDAYDatePicker = uidatepicker(app.UIFigure);
            app.RELATIONSHIPEditFieldLabel = uilabel(app.UIFigure);
            app.RELATIONSHIPEditFieldLabel.Text = 'RELATIONSHIP :';
            app.RELATIONSHIPEditField = uieditfield(app.UIFigure, 'text');
            app.ADDRESSLabel = uilabel(app.UIFigure);
            app.ADDRESSLabel.Text = 'ADDRESS:';
            app.ADDRESSEditField = uieditfield(app.UIFigure, 'text');
            app.Image2_2 = uiimage(app.UIFigure);
            app.Image2_2.ImageSource = fullfile(pathToMLAPP, '88b9cea9f2d6fc48920f86cf8033620e.jpg');
            app.Image5 = uiimage(app.UIFigure);
            app.Image5.ImageSource = fullfile(pathToMLAPP, '5700c04197ee9a4372a35ef16eb78f4e-removebg-preview.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Flight_Emergency

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end