classdef Flight_FlightInformation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        BackButton                  matlab.ui.control.Button
        Image5                      matlab.ui.control.Image
        Image2                      matlab.ui.control.Image
        NextButton                  matlab.ui.control.Button
        PlaceOfDestinationDropDown  matlab.ui.control.DropDown
        PlaceOfDestinationDropDownLabel  matlab.ui.control.Label
        PlaceOfOriginDropDown       matlab.ui.control.DropDown
        PlaceOfOriginDropDownLabel  matlab.ui.control.Label
        Image                       matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            % Get values from dropdowns
            placeOfOrigin = app.PlaceOfOriginDropDown.Value;
            placeOfDestination = app.PlaceOfDestinationDropDown.Value;
            
            % File path and sheet name
            excelFile = 'flightDestination.xlsx';
            sheetName = 1; % Index for the first sheet
        
            % Create new data table
            newData = table({placeOfOrigin}, {placeOfDestination}, 'VariableNames', {'PlaceOfOrigin', 'PlaceOfDestination'});
            
            if isfile(excelFile)
                % Read existing data from the Excel file
                [~, ~, existingData] = xlsread(excelFile, sheetName);
                
                % Convert existing data to a table
                if isempty(existingData)
                    existingTable = table();
                else
                    existingTable = cell2table(existingData(2:end,:), 'VariableNames', existingData(1,:));
                end
                
                % Combine existing data with new data
                updatedTable = [existingTable; newData];
            else
                % If file does not exist, use the new data as the table
                updatedTable = newData;
            end
            
            % Write the updated data back to the Excel file
            writetable(updatedTable, excelFile, 'Sheet', sheetName, 'WriteMode', 'overwrite');
            
            % Optionally, show a success message
            uialert(app.UIFigure, 'Flight information saved successfully.', 'Success');

            delete(app);
            Flight_DestinationDate;
        end

        % Image clicked function: Image5
        function Go_To_Profile(app, event)
            Flight_Profile;
           
            delete(app.UIFigure);
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            Flight_Webpage_Fron;
            delete(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'PassengerDeets.png');
            app.PlaceOfOriginDropDownLabel = uilabel(app.UIFigure);
            app.PlaceOfOriginDropDownLabel.Text = 'Place Of Origin';
            app.PlaceOfOriginDropDown = uidropdown(app.UIFigure);
            app.PlaceOfOriginDropDown.Items = {'Manila, MNL', 'Cebu, CEB', 'Davao, DVO', 'Clark, CRK', 'Iloilo City, ILO', 'Cagayan de Oro, CGY', 'General Santos, GES', 'Zamboanga, ZAM', 'Bacolod, BCD', 'Panglao, TAG', 'Kalibo, KLO', 'Legazpi, LGP', 'Puerto Princesa, PPS', 'Tuguegarao, TUG', 'Butuan, BXU', 'Surigao, SUG', 'Siquijor, SQI'};
            app.PlaceOfOriginDropDown.Value = 'Manila, MNL';
            app.PlaceOfDestinationDropDownLabel = uilabel(app.UIFigure);
            app.PlaceOfDestinationDropDownLabel.Text = 'Place Of Destination';
            app.PlaceOfDestinationDropDown = uidropdown(app.UIFigure);
            app.PlaceOfDestinationDropDown.Items = {'Manila, MNL', 'Cebu, CEB', 'Davao, DVO', 'Clark, CRK', 'Iloilo City, ILO', 'Cagayan de Oro, CGY', 'General Santos, GES', 'Zamboanga, ZAM', 'Bacolod, BCD', 'Panglao, TAG', 'Kalibo, KLO', 'Legazpi, LGP', 'Puerto Princesa, PPS', 'Tuguegarao, TUG', 'Butuan, BXU', 'Surigao, SUG', 'Siquijor, SQI'};
            app.PlaceOfDestinationDropDown.Value = 'Manila, MNL';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, '88b9cea9f2d6fc48920f86cf8033620e.jpg');
            app.Image5 = uiimage(app.UIFigure);
            app.Image5.ImageClickedFcn = createCallbackFcn(app, @Go_To_Profile, true);
            app.Image5.ImageSource = fullfile(pathToMLAPP, '5700c04197ee9a4372a35ef16eb78f4e-removebg-preview.png');
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Flight_FlightInformation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end