classdef Flight_LogIn < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Hyperlink                     matlab.ui.control.Hyperlink
        noaccountyetLabel             matlab.ui.control.Label
        BUCKLEUPButton                matlab.ui.control.Button
        Label                         matlab.ui.control.Label
        PasswordEditField             matlab.ui.control.EditField
        PASSWORDLabel                 matlab.ui.control.Label
        EMAILLabel                    matlab.ui.control.Label
        EmailEditField                matlab.ui.control.EditField
        LOGINLabel                    matlab.ui.control.Label
        WELCOMEtoBESMINAIRLINESLabel  matlab.ui.control.Label
        Image3                        matlab.ui.control.Image
        Image2                        matlab.ui.control.Image
        Image                         matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BUCKLEUPButton
        function BUCKLEUPButtonPushed(app, event)
             Username = app.EmailEditField.Value;
            Password = app.PasswordEditField.Value;
            
            % Validate input fields
            if isempty(Username) || isempty(Password)
                uialert(app.UIFigure, 'Please enter both email and password.', 'Error');
                return;
            end
            
            % Attempt to authenticate the user
            isAuthenticated = app.authenticateUser(Username, Password);
            
            if isAuthenticated
                app.Label.Text = 'Login successful!';
                app.Label.FontColor = 'green';
                % Proceed to next panel or function
                pause(1);
                Flight_Webpage_Front;
                delete(app.UIFigure);
            else
                app.Label.Text = 'Invalid email or password.';
                app.Label.FontColor = 'red';
            end
        end

        % Function to authenticate the user
        function isAuthenticated = authenticateUser(app, Username, Password)
            filename = 'LoginData.xlsx'; % Your Excel file
            try
                % Read the data from the Excel file
                userData = readtable(filename);
                
                % Check if email and password match
                emailMatches = strcmp(userData.Username, Username);
                passwordMatches = strcmp(userData.Password, Password);
                
                if any(emailMatches & passwordMatches)
                    isAuthenticated = true;
                else
                    isAuthenticated = false;
                end
            catch ME
                disp('An error occurred while reading the Excel file:');
                disp(ME.message);
                uialert(app.UIFigure, 'An error occurred while accessing user data.', 'Error');
                isAuthenticated = false;
            end
        end

        % Callback function: Hyperlink
        function HyperlinkClicked(app, event)
            Flight_SignIn;
            delete(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'b9736d91806e3a2b8db1ab3e6a7bb0b0 (1).jpg');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, '702e40edcf6d527a7cb598ba2204d6be.jpg');
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'Est._2024-removebg-preview (1).png');
            app.WELCOMEtoBESMINAIRLINESLabel = uilabel(app.UIFigure);
            app.WELCOMEtoBESMINAIRLINESLabel.Text = 'WELCOME! to BESMIN AIRLINES';
            app.LOGINLabel = uilabel(app.UIFigure);
            app.LOGINLabel.Text = 'LOG-IN';
            app.EmailEditField = uieditfield(app.UIFigure, 'text');
            app.EMAILLabel = uilabel(app.UIFigure);
            app.EMAILLabel.Text = 'EMAIL:';
            app.PASSWORDLabel = uilabel(app.UIFigure);
            app.PASSWORDLabel.Text = 'PASSWORD:';
            app.PasswordEditField = uieditfield(app.UIFigure, 'text');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.BUCKLEUPButton = uibutton(app.UIFigure, 'push');
            app.BUCKLEUPButton.ButtonPushedFcn = createCallbackFcn(app, @BUCKLEUPButtonPushed, true);
            app.BUCKLEUPButton.Text = 'BUCKLE - UP';
            app.noaccountyetLabel = uilabel(app.UIFigure);
            app.noaccountyetLabel.Text = 'no account yet? ';
            app.Hyperlink = uihyperlink(app.UIFigure);
            app.Hyperlink.HyperlinkClickedFcn = createCallbackFcn(app, @HyperlinkClicked, true);
            app.Hyperlink.Text = 'Sign In';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Flight_LogIn

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end