classdef Flight_PassengerDeets < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Image5                      matlab.ui.control.Image
        TypeDropDown                matlab.ui.control.DropDown
        TypeDropDownLabel           matlab.ui.control.Label
        MobileNumberEditField       matlab.ui.control.EditField
        MobileNumberEditFieldLabel  matlab.ui.control.Label
        NoEditingofInformationOnceSubmittedLabel  matlab.ui.control.Label
        SubmitButton                matlab.ui.control.Button
        NationalityDropDown         matlab.ui.control.DropDown
        NationalityDropDownLabel    matlab.ui.control.Label
        EmailEditField              matlab.ui.control.EditField
        EmailEditFieldLabel         matlab.ui.control.Label
        SexDropDown                 matlab.ui.control.DropDown
        SexDropDownLabel            matlab.ui.control.Label
        LastNameEditField           matlab.ui.control.EditField
        LastNameEditFieldLabel      matlab.ui.control.Label
        DateofBirthDatePicker       matlab.ui.control.DatePicker
        DateofBirthDatePickerLabel  matlab.ui.control.Label
        FirstNameEditField          matlab.ui.control.EditField
        FirstNameEditFieldLabel     matlab.ui.control.Label
        PassengersInformationLabel  matlab.ui.control.Label
        Image                       matlab.ui.control.Image
        Image2                      matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           % Initialize seat format
            persistent seatFormat;
            if isempty(seatFormat)
                seatFormat = '01A'; % Initialize with a starting format
            end
            
            % Load or create seat data if needed
            if isfile('seatDetails.mat')
                load('seatDetails.mat', 'seatFormat');
            else
                % Save initial format to file
                save('seatDetails.mat', 'seatFormat');
            end
        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            if isempty(app.FirstNameEditField.Value) || isempty(app.LastNameEditField.Value) || isempty(app.MobileNumberEditField.Value) || isempty(app.EmailEditField.Value) || strcmp(app.SexDropDown.Value,'----') || strcmp(app.NationalityDropDown.Value,'Please Select') || isnat(app.DateofBirthDatePicker.Value)
                uialert(app.UIFigure,'Please fill in all fields before proceeding','Note')
                return;
            end
        
            % Retrieve user inputs
            firstName = app.FirstNameEditField.Value;
            lastName = app.LastNameEditField.Value;
            dob = app.DateofBirthDatePicker.Value;
            email = app.EmailEditField.Value;
            nationality = app.NationalityDropDown.Value;
            sex = app.SexDropDown.Value;
            mobileNumber = app.MobileNumberEditField.Value;
            type = app.TypeDropDown.Value;

            % Retrieve flight details
            if isfile('LoginData.xlsx')
                flightData = readtable('LoginData.xlsx');
                userName = flightData.Username(1);
            else
                uialert(app.UIFigure, 'User details not found. Please go back. ', 'Error');
                return;
            end

            % Retrieve flight details
            if isfile('flightDetails.xlsx')
                flightData = readtable('flightDetails.xlsx');
                departureDate = flightData.DepartureDate(1);
                returnDate = flightData.ReturnDate(1);
            else
                uialert(app.UIFigure, 'Flight details not found. Please go back and enter flight details.', 'Error');
                return;
            end
        
            % Retrieve flight destination details
            if isfile('flightDestination.xlsx')
                flightDestData = readtable('flightDestination.xlsx');
                if ~isempty(flightDestData)
                    % Assuming we want the latest entry
                    if height(flightDestData) > 0
                        placeOfOrigin = flightDestData.PlaceOfOrigin{end};
                        placeOfDestination = flightDestData.PlaceOfDestination{end};
                    else
                        placeOfOrigin = 'No data available';
                        placeOfDestination = 'No data available';
                    end
                else
                    placeOfOrigin = 'No data available';
                    placeOfDestination = 'No data available';
                end
            else
                uialert(app.UIFigure, 'Flight destination details not found. Please go back and enter flight destination details.', 'Error');
                return;
            end
        
            % Load seat format from file
            if isfile('seatDetails.mat')
                load('seatDetails.mat', 'seatFormat');
            else
                % Initialize seat format if file is not found
                seatFormat = '01A';
            end
        
            % Generate seat and ticket numbers
            seatNumber = seatFormat; % Use current seat format
            ticketNumber = sprintf('%d-%04d', 1, randi([1, 9999])); % Generate ticket number
        
            % Update seat format for next use
            seatFormat = updateSeatFormat(app, seatFormat); % Pass 'app' as the first argument
        
            % Save updated seat format to file
            save('seatDetails.mat', 'seatFormat');
        
            % Generate random GateNumber (1-10) and BoardingTime (e.g., 'HH:MM AM/PM')
            gateNumber = randi([1, 10]);
            % Generate boarding time in 'HH:MM AM/PM' format
            hour = randi([1, 12]);
            minute = randi([0, 59]);
            ampm = 'AM';
            if rand() > 0.5
                ampm = 'PM';
            end
            boardingTime = sprintf('%02d:%02d %s', hour, minute, ampm);
            
        
            % Prepare data for writing to Excel
            data = {firstName, lastName, dob, email, nationality, sex, mobileNumber, type, seatNumber, ticketNumber, departureDate, returnDate, placeOfOrigin, placeOfDestination, gateNumber, boardingTime, userName};
            passengerData = cell2table(data, 'VariableNames', {'FirstName', 'LastName', 'DateOfBirth', 'Email', 'Nationality', 'Sex', 'MobileNumber', 'Type', 'SeatNumber', 'TicketNumber', 'DepartureDate', 'ReturnDate', 'PlaceOfOrigin', 'PlaceOfDestination', 'GateNumber', 'BoardingTime', 'Username'});
        
            % Convert data types in passengerData to match existingData
            if isfile('passengerDetails.xlsx')
                existingData = readtable('passengerDetails.xlsx');
        
                % Ensure existingData has the same columns as passengerData
                missingVars = setdiff(passengerData.Properties.VariableNames, existingData.Properties.VariableNames);
                for i = 1:length(missingVars)
                    existingData.(missingVars{i}) = repmat({''}, height(existingData), 1); % Add missing columns with default values
                end
        
                missingVars = setdiff(existingData.Properties.VariableNames, passengerData.Properties.VariableNames);
                for i = 1:length(missingVars)
                    passengerData.(missingVars{i}) = repmat({''}, height(passengerData), 1); % Add missing columns with default values
                end
        
                % Convert data types to match
                existingData = convertToString(app, existingData);
                passengerData = convertToString(app, passengerData);
        
                % Append the new data
                passengerData = passengerData(:, existingData.Properties.VariableNames);
                combinedData = [existingData; passengerData];
            else
                combinedData = passengerData;
            end
        
            % Write the combined data to the Excel file
            writetable(combinedData, 'passengerDetails.xlsx');
        
            % Display success message
            msgbox('Successfully submitted', 'Success');
            % EDITED Flight_TicketPage;
            Passenger_Health;
            delete(app.UIFigure);
        end
        
        % Subfunction to update seat format
        function newFormat = updateSeatFormat(~, currentFormat)
            % Increment seat format
            prefix = currentFormat(3);
            num = str2double(currentFormat(1:2));
        
            num = num + 1;
            if num > 99
                num = 1;
                if prefix == 'A'
                    prefix = 'B';
                elseif prefix == 'B'
                    prefix = 'C';
                else
                    prefix = 'A'; % Loop back to 'A' if needed
                end
            end
        
            newFormat = sprintf('%02d%s', num, prefix);
        end
        
        % Helper function to convert all variables in a table to strings
        function tbl = convertToString(~, tbl)
            vars = tbl.Properties.VariableNames;
            for i = 1:numel(vars)
                if iscell(tbl.(vars{i}))
                    tbl.(vars{i}) = cellfun(@num2str, tbl.(vars{i}), 'UniformOutput', false);
                elseif isnumeric(tbl.(vars{i}))
                    tbl.(vars{i}) = arrayfun(@num2str, tbl.(vars{i}), 'UniformOutput', false);
                end
            end
        end

        % Image clicked function: Image5
        function Go_To_Profile(app, event)
            Flight_Profile
           
            delete(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, '88b9cea9f2d6fc48920f86cf8033620e.jpg');
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'PassengerDeets.png');
            app.PassengersInformationLabel = uilabel(app.UIFigure);
            app.PassengersInformationLabel.Text = 'Passenger''s Information';
            app.FirstNameEditFieldLabel = uilabel(app.UIFigure);
            app.FirstNameEditFieldLabel.Text = 'First Name';
            app.FirstNameEditField = uieditfield(app.UIFigure, 'text');
            app.DateofBirthDatePickerLabel = uilabel(app.UIFigure);
            app.DateofBirthDatePickerLabel.Text = 'Date of Birth';
            app.DateofBirthDatePicker = uidatepicker(app.UIFigure);
            app.LastNameEditFieldLabel = uilabel(app.UIFigure);
            app.LastNameEditFieldLabel.Text = 'Last Name';
            app.LastNameEditField = uieditfield(app.UIFigure, 'text');
            app.SexDropDownLabel = uilabel(app.UIFigure);
            app.SexDropDownLabel.Text = 'Sex';
            app.SexDropDown = uidropdown(app.UIFigure);
            app.SexDropDown.Items = {'----', 'Male', 'Female', 'Prefer not to say'};
            app.SexDropDown.Value = 'Male';
            app.EmailEditFieldLabel = uilabel(app.UIFigure);
            app.EmailEditFieldLabel.Text = 'Email';
            app.EmailEditField = uieditfield(app.UIFigure, 'text');
            app.NationalityDropDownLabel = uilabel(app.UIFigure);
            app.NationalityDropDownLabel.Text = 'Nationality';
            app.NationalityDropDown = uidropdown(app.UIFigure);
            app.NationalityDropDown.Items = {'Please Select', 'Afghanistan', 'Albania', 'Algeria', 'Andorra', 'Angola', 'Antigua and Barbuda', 'Argentina', 'Armenia', 'Australia', 'Austria', 'Azerbaijan', 'Bahamas', 'Bahrain', 'Bangladesh', 'Barbados', 'Belarus', 'Belgium', 'Belize', 'Benin', 'Bhutan', 'Bolivia', 'Bosnia and Herzegovina', 'Botswana', 'Brazil', 'Brunei', 'Bulgaria', 'Burkina Faso', 'Burundi', 'Cabo Verde', 'Cambodia', 'Cameroon', 'Canada', 'Central African Republic', 'Chad', 'Chile', 'China', 'Colombia', 'Comoros', 'Congo (Congo-Brazzaville)', 'Costa Rica', 'Croatia', 'Cuba', 'Cyprus', 'Czechia (Czech Republic)', 'Denmark', 'Djibouti', 'Dominica', 'Dominican Republic', 'East Timor (Timor-Leste)', 'Ecuador', 'Egypt', 'El Salvador', 'Equatorial Guinea', 'Eritrea', 'Estonia', 'Eswatini (fmr. "Swaziland")', 'Ethiopia', 'Fiji', 'Finland', 'France', 'Gabon', 'Gambia', 'Georgia', 'Germany', 'Ghana', 'Greece', 'Grenada', 'Guatemala', 'Guinea', 'Guinea-Bissau', 'Guyana', 'Haiti', 'Honduras', 'Hungary', 'Iceland', 'India', 'Indonesia', 'Iran', 'Iraq', 'Ireland', 'Israel', 'Italy', 'Ivory Coast', 'Jamaica', 'Japan', 'Jordan', 'Kazakhstan', 'Kenya', 'Kiribati', 'Kuwait', 'Kyrgyzstan', 'Laos', 'Latvia', 'Lebanon', 'Lesotho', 'Liberia', 'Libya', 'Liechtenstein', 'Lithuania', 'Luxembourg', 'Madagascar', 'Malawi', 'Malaysia', 'Maldives', 'Mali', 'Malta', 'Marshall Islands', 'Mauritania', 'Mauritius', 'Mexico', 'Micronesia', 'Moldova', 'Monaco', 'Mongolia', 'Montenegro', 'Morocco', 'Mozambique', 'Namibia', 'Nauru', 'Nepal', 'Netherlands', 'New Zealand', 'Nicaragua', 'Niger', 'Nigeria', 'North Korea', 'North Macedonia', 'Norway', 'Oman', 'Pakistan', 'Palau', 'Palestine State', 'Panama', 'Papua New Guinea', 'Paraguay', 'Peru', 'Philippines', 'Poland', 'Portugal', 'Qatar', 'Romania', 'Russia', 'Rwanda', 'Saint Kitts and Nevis', 'Saint Lucia', 'Saint Vincent and the Grenadines', 'Samoa', 'San Marino', 'Sao Tome and Principe', 'Saudi Arabia', 'Senegal', 'Serbia', 'Seychelles', 'Sierra Leone', 'Singapore', 'Slovakia', 'Slovenia', 'Solomon Islands', 'Somalia', 'South Africa', 'South Korea', 'South Sudan', 'Spain', 'Sri Lanka', 'Sudan', 'Suriname', 'Sweden', 'Switzerland', 'Syria', 'Taiwan', 'Tajikistan', 'Tanzania', 'Thailand', 'Togo', 'Tonga', 'Trinidad and Tobago', 'Tunisia', 'Turkey', 'Turkmenistan', 'Tuvalu', 'Uganda', 'Ukraine', 'United Arab Emirates', 'United Kingdom', 'United States of America', 'Uruguay', 'Uzbekistan', 'Vanuatu', 'Venezuela', 'Vietnam', 'Yemen', 'Zambia', 'Zimbabwe'};
            app.NationalityDropDown.Value = 'Please Select';
            app.SubmitButton = uibutton(app.UIFigure, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
            app.NoEditingofInformationOnceSubmittedLabel = uilabel(app.UIFigure);
            app.NoEditingofInformationOnceSubmittedLabel.Text = 'No Editing of Information Once Submitted';
            app.MobileNumberEditFieldLabel = uilabel(app.UIFigure);
            app.MobileNumberEditFieldLabel.Text = 'Mobile Number';
            app.MobileNumberEditField = uieditfield(app.UIFigure, 'text');
            app.TypeDropDownLabel = uilabel(app.UIFigure);
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.UIFigure);
            app.TypeDropDown.Items = {'---', 'Adult', 'Child'};
            app.TypeDropDown.Value = '---';
            app.Image5 = uiimage(app.UIFigure);
            app.Image5.ImageClickedFcn = createCallbackFcn(app, @Go_To_Profile, true);
            app.Image5.ImageSource = fullfile(pathToMLAPP, '5700c04197ee9a4372a35ef16eb78f4e-removebg-preview.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Flight_PassengerDeets

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end