classdef Flight_Profile < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        HomeButton                matlab.ui.control.Button
        Hyperlink                 matlab.ui.control.Hyperlink
        Image2                    matlab.ui.control.Image
        Image5                    matlab.ui.control.Image
        Image2_2                  matlab.ui.control.Image
        EmergencyContactLabel     matlab.ui.control.Label
        BookingsLabel             matlab.ui.control.Label
        PersonalInformationLabel  matlab.ui.control.Label
        ECAddressLabel            matlab.ui.control.Label
        ECNumberLabel             matlab.ui.control.Label
        ECEmailLabel              matlab.ui.control.Label
        ECDOBLabel                matlab.ui.control.Label
        ECAgeLabel                matlab.ui.control.Label
        ECNameLabel               matlab.ui.control.Label
        ADDRESSLabel              matlab.ui.control.Label
        NOLabel_2                 matlab.ui.control.Label
        EmailLabel_3              matlab.ui.control.Label
        DOBLabel_2                matlab.ui.control.Label
        RelationshipLabel         matlab.ui.control.Label
        NameLabel_2               matlab.ui.control.Label
        NumberLabel               matlab.ui.control.Label
        NOLabel                   matlab.ui.control.Label
        AgeLabel                  matlab.ui.control.Label
        AGELabel                  matlab.ui.control.Label
        TicketNumberLabel         matlab.ui.control.Label
        TicketNoLabel             matlab.ui.control.Label
        ReturnLabel               matlab.ui.control.Label
        DepartureLabel            matlab.ui.control.Label
        DestinationLabel          matlab.ui.control.Label
        OriginLabel               matlab.ui.control.Label
        DOBLabel                  matlab.ui.control.Label
        EmailLabel_2              matlab.ui.control.Label
        NameLabel                 matlab.ui.control.Label
        PlaceOfDestinationLabel   matlab.ui.control.Label
        PlaceOfOriginLabel        matlab.ui.control.Label
        ReturnDateLabel           matlab.ui.control.Label
        DepartureDateLabel        matlab.ui.control.Label
        DateOfBirthLabel          matlab.ui.control.Label
        EmailLabel                matlab.ui.control.Label
        LastNameLabel             matlab.ui.control.Label
        FirstNameLabel            matlab.ui.control.Label
        Image3_2                  matlab.ui.control.Image
        Image3_3                  matlab.ui.control.Image
        Image3                    matlab.ui.control.Image
        Image                     matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
     
            % Define file paths and sheet names
            flightDestFile = 'flightDestination.xlsx';
            passengerDetailsFile = 'passengerDetails.xlsx';
            flightDetailsFile = 'flightDetails.xlsx';
            loginDataFile = 'LoginData.xlsx';
            emergencyContactFile = 'FlightEmergency.xlsx'; % File name for emergency contacts
            sheetName = 1; % Index for the first sheet in each file
        
            % Read data from flightDestination.xlsx
            if isfile(flightDestFile)
                flightDestData = readtable(flightDestFile, 'Sheet', sheetName);
                if ~isempty(flightDestData)
                    % Display the most recent entry
                    if height(flightDestData) > 0
                        app.PlaceOfOriginLabel.Text = flightDestData.PlaceOfOrigin{end};
                        app.PlaceOfDestinationLabel.Text = flightDestData.PlaceOfDestination{end};
                    else
                        app.PlaceOfOriginLabel.Text = 'No data available';
                        app.PlaceOfDestinationLabel.Text = 'No data available';
                    end
                else
                    app.PlaceOfOriginLabel.Text = 'No data available';
                    app.PlaceOfDestinationLabel.Text = 'No data available';
                end
            else
                app.PlaceOfOriginLabel.Text = 'File not found';
                app.PlaceOfDestinationLabel.Text = 'File not found';
            end
        
            % Read data from passengerDetails.xlsx
            if isfile(passengerDetailsFile)
                passengerData = readtable(passengerDetailsFile, 'Sheet', sheetName);
                if ~isempty(passengerData)
                    % Display the most recent entry
                    if height(passengerData) > 0
                        app.FirstNameLabel.Text = passengerData.FirstName{end};
                        app.LastNameLabel.Text = passengerData.LastName{end};
                        app.DateOfBirthLabel.Text = datestr(passengerData.DateOfBirth(end), 'mm/dd/yyyy');
                        app.EmailLabel.Text = passengerData.Email{end};
                        app.NumberLabel.Text = passengerData.MobileNumber{end}; % Add new label
                        app.TicketNumberLabel.Text = string(passengerData.TicketNumber{end}); % Add new label
                    else
                        app.FirstNameLabel.Text = 'No data available';
                        app.LastNameLabel.Text = 'No data available';
                        app.DateOfBirthLabel.Text = 'No data available';
                        app.EmailLabel.Text = 'No data available';
                        app.NumberLabel.Text = 'No data available'; % Add new label
                        app.TicketNumberLabel.Text = 'No data available'; % Add new label
                    end
                else
                    app.FirstNameLabel.Text = 'No data available';
                    app.LastNameLabel.Text = 'No data available';
                    app.DateOfBirthLabel.Text = 'No data available';
                    app.EmailLabel.Text = 'No data available';
                    app.NumberLabel.Text = 'No data available'; % Add new label
                    app.TicketNumberLabel.Text = 'No data available'; % Add new label
                end
            else
                app.FirstNameLabel.Text = 'File not found';
                app.LastNameLabel.Text = 'File not found';
                app.DateOfBirthLabel.Text = 'File not found';
                app.EmailLabel.Text = 'File not found';
                app.NumberLabel.Text = 'File not found'; % Add new label
                app.TicketNumberLabel.Text = 'File not found'; % Add new label
            end
        
            % Read data from flightDetails.xlsx
            if isfile(flightDetailsFile)
                flightDetailsData = readtable(flightDetailsFile, 'Sheet', sheetName);
                if ~isempty(flightDetailsData)
                    % Display the most recent entry
                    if height(flightDetailsData) > 0
                        app.DepartureDateLabel.Text = datestr(flightDetailsData.DepartureDate(end), 'mm/dd/yyyy');
                        app.ReturnDateLabel.Text = datestr(flightDetailsData.ReturnDate(end), 'mm/dd/yyyy');
                    else
                        app.DepartureDateLabel.Text = 'No data available';
                        app.ReturnDateLabel.Text = 'No data available';
                    end
                else
                    app.DepartureDateLabel.Text = 'No data available';
                    app.ReturnDateLabel.Text = 'No data available';
                end
            else
                app.DepartureDateLabel.Text = 'File not found';
                app.ReturnDateLabel.Text = 'File not found';
            end
        
            % Read data from emergencyContact.xlsx
            if isfile(emergencyContactFile)
                emergencyContactData = readtable(emergencyContactFile, 'Sheet', sheetName);
                if ~isempty(emergencyContactData)
                    % Display the most recent entry
                    if height(emergencyContactData) > 0
                        app.ECNameLabel.Text = emergencyContactData.ECName{end};
                        app.ECNumberLabel.Text = emergencyContactData.ECNumber{end};
                        app.ECEmailLabel.Text = emergencyContactData.ECEmail{end};
                        app.ECDOBLabel.Text = datestr(emergencyContactData.ECDOB(end));
                        app.ECAgeLabel.Text = emergencyContactData.ECRelationship(end);
                        app.ECAddressLabel.Text = emergencyContactData.ECAddress{end};
                    else
                        app.ECNameLabel.Text = 'No data available';
                        app.ECNumberLabel.Text = 'No data available';
                        app.ECEmailLabel.Text = 'No data available';
                        app.ECDOBLabel.Text = 'No data available';
                        app.ECAgeLabel.Text = 'No data available';
                        app.ECAddressLabel.Text = 'No data available';
                    end
                else
                    app.ECNameLabel.Text = 'No data available';
                    app.ECNumberLabel.Text = 'No data available';
                    app.ECEmailLabel.Text = 'No data available';
                    app.ECDOBLabel.Text = 'No data available';
                    app.ECAgeLabel.Text = 'No data available';
                    app.ECAddressLabel.Text = 'No data available';
                end
            else
                app.ECNameLabel.Text = 'File not found';
                app.ECNumberLabel.Text = 'File not found';
                app.ECEmailLabel.Text = 'File not found';
                app.ECDOBLabel.Text = 'File not found';
                app.ECAgeLabel.Text = 'File not found';
                app.ECAddressLabel.Text = 'File not found';
            end

        end

        % Image clicked function: Image5
        function Go_To_Profile(app, event)
            Flight_Profile;
            delete(app.UIFigure);
        end

        % Callback function: Hyperlink
        function Full_Tickets(app, event)
            Flight_FullTicket;
            delete(app.UIFigure);
        end

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
            Flight_Webpage_Front;
            delete(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'b9736d91806e3a2b8db1ab3e6a7bb0b0 (1).jpg');
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, '702e40edcf6d527a7cb598ba2204d6be.jpg');
            app.Image3_3 = uiimage(app.UIFigure);
            app.Image3_3.ImageSource = fullfile(pathToMLAPP, '702e40edcf6d527a7cb598ba2204d6be.jpg');
            app.Image3_2 = uiimage(app.UIFigure);
            app.Image3_2.ImageSource = fullfile(pathToMLAPP, '702e40edcf6d527a7cb598ba2204d6be.jpg');
            app.FirstNameLabel = uilabel(app.UIFigure);
            app.FirstNameLabel.Text = 'First Name';
            app.LastNameLabel = uilabel(app.UIFigure);
            app.LastNameLabel.Text = 'Last Name';
            app.EmailLabel = uilabel(app.UIFigure);
            app.EmailLabel.Text = 'Email';
            app.DateOfBirthLabel = uilabel(app.UIFigure);
            app.DateOfBirthLabel.Text = 'DOB';
            app.DepartureDateLabel = uilabel(app.UIFigure);
            app.DepartureDateLabel.Text = 'DEPARTURE';
            app.ReturnDateLabel = uilabel(app.UIFigure);
            app.ReturnDateLabel.Text = 'RETURN';
            app.PlaceOfOriginLabel = uilabel(app.UIFigure);
            app.PlaceOfOriginLabel.Text = 'ORIGIN';
            app.PlaceOfDestinationLabel = uilabel(app.UIFigure);
            app.PlaceOfDestinationLabel.Text = 'DESTINATION';
            app.NameLabel = uilabel(app.UIFigure);
            app.NameLabel.Text = 'Name:';
            app.EmailLabel_2 = uilabel(app.UIFigure);
            app.EmailLabel_2.Text = 'Email:';
            app.DOBLabel = uilabel(app.UIFigure);
            app.DOBLabel.Text = 'DOB:';
            app.OriginLabel = uilabel(app.UIFigure);
            app.OriginLabel.Text = 'Origin:';
            app.DestinationLabel = uilabel(app.UIFigure);
            app.DestinationLabel.Text = 'Destination:';
            app.DepartureLabel = uilabel(app.UIFigure);
            app.DepartureLabel.Text = 'Departure:';
            app.ReturnLabel = uilabel(app.UIFigure);
            app.ReturnLabel.Text = 'Return:';
            app.TicketNoLabel = uilabel(app.UIFigure);
            app.TicketNoLabel.Text = 'Ticket No.:';
            app.TicketNumberLabel = uilabel(app.UIFigure);
            app.TicketNumberLabel.Text = 'TICKETNUMBER';
            app.AGELabel = uilabel(app.UIFigure);
            app.AGELabel.Text = 'AGE:';
            app.AgeLabel = uilabel(app.UIFigure);
            app.AgeLabel.Text = 'Age';
            app.NOLabel = uilabel(app.UIFigure);
            app.NOLabel.Text = 'NO.:';
            app.NumberLabel = uilabel(app.UIFigure);
            app.NumberLabel.Text = 'Number';
            app.NameLabel_2 = uilabel(app.UIFigure);
            app.NameLabel_2.Text = 'Name:';
            app.RelationshipLabel = uilabel(app.UIFigure);
            app.RelationshipLabel.Text = 'Relationship:';
            app.DOBLabel_2 = uilabel(app.UIFigure);
            app.DOBLabel_2.Text = 'DOB:';
            app.EmailLabel_3 = uilabel(app.UIFigure);
            app.EmailLabel_3.Text = 'Email:';
            app.NOLabel_2 = uilabel(app.UIFigure);
            app.NOLabel_2.Text = 'NO.:';
            app.ADDRESSLabel = uilabel(app.UIFigure);
            app.ADDRESSLabel.Text = 'ADDRESS:';
            app.ECNameLabel = uilabel(app.UIFigure);
            app.ECNameLabel.Text = 'First Name';
            app.ECAgeLabel = uilabel(app.UIFigure);
            app.ECAgeLabel.Text = 'Relation';
            app.ECDOBLabel = uilabel(app.UIFigure);
            app.ECDOBLabel.Text = 'DOB';
            app.ECEmailLabel = uilabel(app.UIFigure);
            app.ECEmailLabel.Text = 'Email';
            app.ECNumberLabel = uilabel(app.UIFigure);
            app.ECNumberLabel.Text = 'Number';
            app.ECAddressLabel = uilabel(app.UIFigure);
            app.ECAddressLabel.Text = 'Address';
            app.PersonalInformationLabel = uilabel(app.UIFigure);
            app.PersonalInformationLabel.Text = 'Personal Information';
            app.BookingsLabel = uilabel(app.UIFigure);
            app.BookingsLabel.Text = 'Bookings';
            app.EmergencyContactLabel = uilabel(app.UIFigure);
            app.EmergencyContactLabel.Text = 'Emergency Contact';
            app.Image2_2 = uiimage(app.UIFigure);
            app.Image2_2.ImageSource = fullfile(pathToMLAPP, '88b9cea9f2d6fc48920f86cf8033620e.jpg');
            app.Image5 = uiimage(app.UIFigure);
            app.Image5.ImageClickedFcn = createCallbackFcn(app, @Go_To_Profile, true);
            app.Image5.ImageSource = fullfile(pathToMLAPP, '5700c04197ee9a4372a35ef16eb78f4e-removebg-preview.png');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Est._2024-removebg-preview (1).png');
            app.Hyperlink = uihyperlink(app.UIFigure);
            app.Hyperlink.HyperlinkClickedFcn = createCallbackFcn(app, @Full_Tickets, true);
            app.Hyperlink.Text = {'see full ticket'; ''};
            app.HomeButton = uibutton(app.UIFigure, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Text = 'Home';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Flight_Profile

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end