classdef Flight_SignIn < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Hyperlink                     matlab.ui.control.Hyperlink
        alreadyhaveanaccountLabel     matlab.ui.control.Label
        DatePicker                    matlab.ui.control.DatePicker
        SUBMITButton                  matlab.ui.control.Button
        BIRTHDAYLabel                 matlab.ui.control.Label
        Spinner                       matlab.ui.control.Spinner
        AGELabel                      matlab.ui.control.Label
        EditField3                    matlab.ui.control.EditField
        NAMELabel                     matlab.ui.control.Label
        EditField2                    matlab.ui.control.EditField
        PASSWORDLabel                 matlab.ui.control.Label
        EMAILLabel                    matlab.ui.control.Label
        EditField                     matlab.ui.control.EditField
        SIGNINLabel                   matlab.ui.control.Label
        WELCOMEtoBESMINAIRLINESLabel  matlab.ui.control.Label
        Image3                        matlab.ui.control.Image
        Image2                        matlab.ui.control.Image
        Image                         matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SUBMITButton
        function SUBMITButtonPushed(app, event)
            data.Username = app.EditField.Value;
            data.Password = app.EditField2.Value;
            data.Name = app.EditField3.Value;
            data.Age = app.Spinner.Value;
            data.Birthday = datestr(app.DatePicker.Value, 'mm/dd/yyyy'); % Convert date to string format
        
            % Debugging message
            disp(['Birthday: ', data.Birthday]);
        
            % Validate input fields
            if isempty(data.Username) || isempty(data.Password) || isempty(data.Name) || isempty(data.Birthday)
                uialert(app.UIFigure, 'Please fill in all fields', 'Error');
            else
                try
                    disp('Attempting to write to Excel file...');
                    response = writeToExcelFile(app,data); % Note: No need to pass 'app' as argument
        
                    if isfield(response, 'error')
                        disp('Error response from Excel file:');
                        disp(response.error);
                        uialert(app.UIFigure, ['An error occurred while saving data. Please try again. Details: ', response.error], 'Error');
                    else
                        uialert(app.UIFigure, 'Login successful and data saved to Excel file', 'Success');
                        % Clear fields after submission
                        app.EditField.Value = '';
                        app.EditField2.Value = '';
                        app.EditField3.Value = '';
                        app.Spinner.Value = 0;
                        app.DatePicker.Value = NaT;
                        pause(2);
                        Flight_Webpage_Front;
                        delete(app.UIFigure);

                    end
        
                catch ME
                    disp('An error occurred while saving the data:');
                    disp(ME.message);
                    uialert(app.UIFigure, ['An error occurred while saving data. Please try again. Details: ', ME.message], 'Error');
                end
            end
        end
        
        % Function to write data to Excel file
        function response = writeToExcelFile(~,data)
            filename = 'LoginData.xlsx';
            try
                % Check if file exists
                if isfile(filename)
                    % Read existing data
                    existingData = readtable(filename);
                    % Append new data
                    newData = struct2table(data, 'AsArray', true);
                    combinedData = [existingData; newData];
                    % Write combined data to file
                    writetable(combinedData, filename);
                else
                    % If file doesn't exist, create new table and write to file
                    newData = struct2table(data, 'AsArray', true);
                    writetable(newData, filename);
                end
                disp('Data written to Excel file successfully');
                response = struct(); % No error, return empty struct
            catch ME
                disp('An error occurred during the writetable operation:');
                disp(ME.message);
                response = struct('error', ME.message);
            end
        end

        % Callback function: Hyperlink
        function HyperlinkClicked(app, event)
            Flight_LogIn;
            delete(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'b9736d91806e3a2b8db1ab3e6a7bb0b0 (1).jpg');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.Tooltip = {'Create your own account here.'};
            app.Image2.ImageSource = fullfile(pathToMLAPP, '702e40edcf6d527a7cb598ba2204d6be.jpg');
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'Est._2024-removebg-preview (1).png');
            app.WELCOMEtoBESMINAIRLINESLabel = uilabel(app.UIFigure);
            app.WELCOMEtoBESMINAIRLINESLabel.Text = 'WELCOME! to BESMIN AIRLINES';
            app.SIGNINLabel = uilabel(app.UIFigure);
            app.SIGNINLabel.Text = 'SIGN IN';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EMAILLabel = uilabel(app.UIFigure);
            app.EMAILLabel.Text = 'EMAIL:';
            app.PASSWORDLabel = uilabel(app.UIFigure);
            app.PASSWORDLabel.Text = 'PASSWORD:';
            app.EditField2 = uieditfield(app.UIFigure, 'text');
            app.NAMELabel = uilabel(app.UIFigure);
            app.NAMELabel.Text = 'NAME:';
            app.EditField3 = uieditfield(app.UIFigure, 'text');
            app.AGELabel = uilabel(app.UIFigure);
            app.AGELabel.Text = 'AGE:';
            app.Spinner = uispinner(app.UIFigure);
            app.BIRTHDAYLabel = uilabel(app.UIFigure);
            app.BIRTHDAYLabel.Text = 'BIRTHDAY:';
            app.SUBMITButton = uibutton(app.UIFigure, 'push');
            app.SUBMITButton.ButtonPushedFcn = createCallbackFcn(app, @SUBMITButtonPushed, true);
            app.SUBMITButton.Text = 'SUBMIT';
            app.DatePicker = uidatepicker(app.UIFigure);
            app.alreadyhaveanaccountLabel = uilabel(app.UIFigure);
            app.alreadyhaveanaccountLabel.Text = 'already have an account? ';
            app.Hyperlink = uihyperlink(app.UIFigure);
            app.Hyperlink.HyperlinkClickedFcn = createCallbackFcn(app, @HyperlinkClicked, true);
            app.Hyperlink.Text = 'Log In';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Flight_SignIn

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end