classdef Flight_TicketPage < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        SeatNumberLabel          matlab.ui.control.Label
        SeatLabel                matlab.ui.control.Label
        BoardingTimeLabel        matlab.ui.control.Label
        BoardingLabel            matlab.ui.control.Label
        GateNumberLabel          matlab.ui.control.Label
        GateNoLabel              matlab.ui.control.Label
        Image4_2                 matlab.ui.control.Image
        Image4                   matlab.ui.control.Image
        TicketNumberLabel        matlab.ui.control.Label
        TicketNoLabel            matlab.ui.control.Label
        ReturnLabel              matlab.ui.control.Label
        DepartureLabel           matlab.ui.control.Label
        PlaceOfDestinationLabel  matlab.ui.control.Label
        PlaceOfOriginLabel       matlab.ui.control.Label
        ReturnDateLabel          matlab.ui.control.Label
        DepartureDateLabel       matlab.ui.control.Label
        NameLabel                matlab.ui.control.Label
        LastNameLabel            matlab.ui.control.Label
        FirstNameLabel           matlab.ui.control.Label
        wherebestluxuriesarebareminimumLabel  matlab.ui.control.Label
        BESMINAIRLINESLabel      matlab.ui.control.Label
        Image8                   matlab.ui.control.Image
        Image7                   matlab.ui.control.Image
        BOOKINGSUCCESSFULLabel   matlab.ui.control.Label
        HomeButton_2             matlab.ui.control.Button
        AccountButton            matlab.ui.control.Button
        BookAgainButton          matlab.ui.control.Button
        Image6                   matlab.ui.control.Image
        Image2_2                 matlab.ui.control.Image
        Image5                   matlab.ui.control.Image
        Image2                   matlab.ui.control.Image
        Image                    matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Define file paths and sheet names
            flightDestFile = 'flightDestination.xlsx';
            flightDetailsFile = 'flightDetails.xlsx';
            passengerDetailsFile = 'passengerDetails.xlsx';
            sheetName = 1; % Index for the first sheet in each file
        
            % Read data from flightDestination.xlsx
            if isfile(flightDestFile)
                flightDestData = readtable(flightDestFile, 'Sheet', sheetName);
                if ~isempty(flightDestData)
                    % Display the most recent entry
                    if height(flightDestData) > 0
                        app.PlaceOfOriginLabel.Text = flightDestData.PlaceOfOrigin{end};
                        app.PlaceOfDestinationLabel.Text = flightDestData.PlaceOfDestination{end};
                    else
                        app.PlaceOfOriginLabel.Text = 'No data available';
                        app.PlaceOfDestinationLabel.Text = 'No data available';
                    end
                else
                    app.PlaceOfOriginLabel.Text = 'No data available';
                    app.PlaceOfDestinationLabel.Text = 'No data available';
                end
            else
                app.PlaceOfOriginLabel.Text = 'File not found';
                app.PlaceOfDestinationLabel.Text = 'File not found';
            end
        
            % Read data from passengerDetails.xlsx
            if isfile(passengerDetailsFile)
                passengerData = readtable(passengerDetailsFile, 'Sheet', sheetName);
                if ~isempty(passengerData)
                    app.FirstNameLabel.Text = passengerData.FirstName{end};
                    app.LastNameLabel.Text = passengerData.LastName{end};
                    app.SeatNumberLabel.Text = string(passengerData.SeatNumber(end)); % Updated to string
                    app.TicketNumberLabel.Text = string(passengerData.TicketNumber(end)); % Updated to string
                    app.GateNumberLabel.Text = string(passengerData.GateNumber(end)); % Display GateNumber
                    app.BoardingTimeLabel.Text = string(passengerData.BoardingTime(end)); % Display BoardingTime
                else
                    app.FirstNameLabel.Text = 'No data available';
                    app.LastNameLabel.Text = 'No data available';
                    app.SeatNumberLabel.Text = 'No data available'; % Updated to string
                    app.TicketNumberLabel.Text = 'No data available'; % Updated to string
                    app.GateNumberLabel.Text = 'No data available'; % Updated to string
                    app.BoardingTimeLabel.Text = 'No data available'; % Updated to string
                end
            else
                app.FirstNameLabel.Text = 'File not found';
                app.LastNameLabel.Text = 'File not found';
                app.SeatNumberLabel.Text = 'File not found'; % Updated to string
                app.TicketNumberLabel.Text = 'File not found'; % Updated to string
                app.GateNumberLabel.Text = 'File not found'; % Updated to string
                app.BoardingTimeLabel.Text = 'File not found'; % Updated to string
            end
        
            % Read data from flightDetails.xlsx
            if isfile(flightDetailsFile)
                flightDetailsData = readtable(flightDetailsFile, 'Sheet', sheetName);
                if ~isempty(flightDetailsData)
                    % Display the most recent entry
                    if height(flightDetailsData) > 0
                        app.DepartureDateLabel.Text = datestr(flightDetailsData.DepartureDate(end), 'mm/dd/yyyy');
                        app.ReturnDateLabel.Text = datestr(flightDetailsData.ReturnDate(end), 'mm/dd/yyyy');
                    else
                        app.DepartureDateLabel.Text = 'No data available';
                        app.ReturnDateLabel.Text = 'No data available';
                    end
                else
                    app.DepartureDateLabel.Text = 'No data available';
                    app.ReturnDateLabel.Text = 'No data available';
                end
            else
                app.DepartureDateLabel.Text = 'File not found';
                app.ReturnDateLabel.Text = 'File not found';
            end
        end

        % Callback function: AccountButton, Image5
        function Go_To_Profile(app, event)
            Flight_Profile;
            delete(app.UIFigure);
        end

        % Button pushed function: HomeButton_2
        function Home(app, event)
            Flight_Webpage_Front;
            delete(app.UIFigure);
        end

        % Button pushed function: BookAgainButton
        function Book_Again(app, event)
            Flight_FlightInformation;
            delete(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'b9736d91806e3a2b8db1ab3e6a7bb0b0 (1).jpg');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, '88b9cea9f2d6fc48920f86cf8033620e.jpg');
            app.Image5 = uiimage(app.UIFigure);
            app.Image5.ImageClickedFcn = createCallbackFcn(app, @Go_To_Profile, true);
            app.Image5.ImageSource = fullfile(pathToMLAPP, '5700c04197ee9a4372a35ef16eb78f4e-removebg-preview.png');
            app.Image2_2 = uiimage(app.UIFigure);
            app.Image2_2.ImageSource = fullfile(pathToMLAPP, 'Est._2024-removebg-preview (1).png');
            app.Image6 = uiimage(app.UIFigure);
            app.Image6.ImageSource = fullfile(pathToMLAPP, '702e40edcf6d527a7cb598ba2204d6be.jpg');
            app.BookAgainButton = uibutton(app.UIFigure, 'push');
            app.BookAgainButton.ButtonPushedFcn = createCallbackFcn(app, @Book_Again, true);
            app.BookAgainButton.Text = 'Book Again';
            app.AccountButton = uibutton(app.UIFigure, 'push');
            app.AccountButton.ButtonPushedFcn = createCallbackFcn(app, @Go_To_Profile, true);
            app.AccountButton.Text = 'Account';
            app.HomeButton_2 = uibutton(app.UIFigure, 'push');
            app.HomeButton_2.ButtonPushedFcn = createCallbackFcn(app, @Home, true);
            app.HomeButton_2.Text = 'Home';
            app.BOOKINGSUCCESSFULLabel = uilabel(app.UIFigure);
            app.BOOKINGSUCCESSFULLabel.Text = 'BOOKING SUCCESSFUL';
            app.Image7 = uiimage(app.UIFigure);
            app.Image7.ImageSource = fullfile(pathToMLAPP, 'Untitled design (1) (1).jpg');
            app.Image8 = uiimage(app.UIFigure);
            app.Image8.ImageSource = fullfile(pathToMLAPP, 'Est._2024-removebg-preview (1).png');
            app.BESMINAIRLINESLabel = uilabel(app.UIFigure);
            app.BESMINAIRLINESLabel.Text = 'BESMIN AIRLINES';
            app.wherebestluxuriesarebareminimumLabel = uilabel(app.UIFigure);
            app.wherebestluxuriesarebareminimumLabel.Text = 'where best luxuries are bare minimum';
            app.FirstNameLabel = uilabel(app.UIFigure);
            app.FirstNameLabel.Text = 'First Name';
            app.LastNameLabel = uilabel(app.UIFigure);
            app.LastNameLabel.Text = 'Last Name';
            app.NameLabel = uilabel(app.UIFigure);
            app.NameLabel.Text = 'Name:';
            app.DepartureDateLabel = uilabel(app.UIFigure);
            app.DepartureDateLabel.Text = 'DEPARTURE';
            app.ReturnDateLabel = uilabel(app.UIFigure);
            app.ReturnDateLabel.Text = 'RETURN';
            app.PlaceOfOriginLabel = uilabel(app.UIFigure);
            app.PlaceOfOriginLabel.Text = 'ORIGIN';
            app.PlaceOfDestinationLabel = uilabel(app.UIFigure);
            app.PlaceOfDestinationLabel.Text = 'DESTINATION';
            app.DepartureLabel = uilabel(app.UIFigure);
            app.DepartureLabel.Text = 'Departure:';
            app.ReturnLabel = uilabel(app.UIFigure);
            app.ReturnLabel.Text = 'Return:';
            app.TicketNoLabel = uilabel(app.UIFigure);
            app.TicketNoLabel.Text = 'Ticket No.:';
            app.TicketNumberLabel = uilabel(app.UIFigure);
            app.TicketNumberLabel.Text = 'TICKETNUMBER';
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = fullfile(pathToMLAPP, '3995e1196e99d10da48bb5e6a5ac8cac.jpg');
            app.Image4_2 = uiimage(app.UIFigure);
            app.Image4_2.ImageSource = fullfile(pathToMLAPP, '3995e1196e99d10da48bb5e6a5ac8cac.jpg');
            app.GateNoLabel = uilabel(app.UIFigure);
            app.GateNoLabel.Text = 'Gate No.:';
            app.GateNumberLabel = uilabel(app.UIFigure);
            app.GateNumberLabel.Text = 'GATE';
            app.BoardingLabel = uilabel(app.UIFigure);
            app.BoardingLabel.Text = 'Boarding Time:';
            app.BoardingTimeLabel = uilabel(app.UIFigure);
            app.BoardingTimeLabel.Text = 'BOARDING TIME';
            app.SeatLabel = uilabel(app.UIFigure);
            app.SeatLabel.Text = 'Seat:';
            app.SeatNumberLabel = uilabel(app.UIFigure);
            app.SeatNumberLabel.Text = 'SEATNUMBER';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Flight_TicketPage

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end