classdef Flight_Webpage_Front < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Image4_6                 matlab.ui.control.Image
        Image4_5                 matlab.ui.control.Image
        LOOKFORMOREFLIGHTCLICKBELOWTOSEARCHFORMOREFLIGHTLabel  matlab.ui.control.Label
        Hyperlink_4              matlab.ui.control.Hyperlink
        BOARDINGTIME1800Label_3  matlab.ui.control.Label
        MANILAMNLLabel_2         matlab.ui.control.Label
        Image4_4                 matlab.ui.control.Image
        CEBUCEBLabel             matlab.ui.control.Label
        Hyperlink_3              matlab.ui.control.Hyperlink
        Image7_3                 matlab.ui.control.Image
        BOARDINGTIME1300Label    matlab.ui.control.Label
        DAVAODVOLabel_2          matlab.ui.control.Label
        ROXASRXSLabel            matlab.ui.control.Label
        Hyperlink_2              matlab.ui.control.Hyperlink
        Image7_2                 matlab.ui.control.Image
        BOARDINGTIME1800Label    matlab.ui.control.Label
        DAVAODVOLabel            matlab.ui.control.Label
        Image6_2                 matlab.ui.control.Image
        MANILAMNLLabel           matlab.ui.control.Label
        Hyperlink                matlab.ui.control.Hyperlink
        Image6_3                 matlab.ui.control.Image
        wherebestluxuriesarebareminimumLabel  matlab.ui.control.Label
        BESMINAIRLINESLabel      matlab.ui.control.Label
        Image6_4                 matlab.ui.control.Image
        Image7                   matlab.ui.control.Image
        FEATUREDFLIGHTSLabel     matlab.ui.control.Label
        Image6                   matlab.ui.control.Image
        Image5                   matlab.ui.control.Image
        Image4                   matlab.ui.control.Image
        Image                    matlab.ui.control.Image
        Image3                   matlab.ui.control.Image
        Image2                   matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: Hyperlink
        function MNL_DVO_BOOK(app, event)
            placeOfOrigin = app.MANILAMNLLabel.Text;
            placeOfDestination = app.DAVAODVOLabel.Text;
            
            % Create a table with new data
            newData = {placeOfOrigin, placeOfDestination};
            newTable = cell2table(newData, 'VariableNames', {'PlaceOfOrigin', 'PlaceOfDestination'});
            
            % File path
            filePath = 'flightDestination.xlsx';
        
            if isfile(filePath)
                % Read existing data from the file
                [~, ~, existingData] = xlsread(filePath);
                % Convert existing data to a table
                existingTable = cell2table(existingData(2:end,:), 'VariableNames', existingData(1,:));
                
                % Append the new data to the existing table
                updatedTable = [existingTable; newTable];
            else
                % If the file does not exist, use the new data as the table
                updatedTable = newTable;
            end
        
            % Write the updated data back to the Excel file
            writetable(updatedTable, filePath, 'WriteMode', 'overwrite'); % this overwrites the entire sheet
            
            % Close the current app and open the next panel
            delete(app);
            Flight_DestinationDate;
        end

        % Callback function: Hyperlink_2
        function RXS_DVO_BOOK(app, event)
            % Data to be transferred
            placeOfOrigin = app.DAVAODVOLabel.Text;
            placeOfDestination = app.ROXASRXSLabel.Text;
        
            % Check if Excel file exists and read existing data
            excelFile = 'passengerDetails.xlsx';
            sheetName = 'Sheet1';
            
            if isfile(excelFile)
                % Read existing data from the Excel file
                [~, ~, existingData] = xlsread(excelFile, sheetName);
                % Convert existing data to a table
                if isempty(existingData)
                    existingTable = table();
                else
                    existingTable = cell2table(existingData(2:end,:), 'VariableNames', existingData(1,:));
                end
            else
                existingTable = table();
            end
        
            % Create new data table
            newData = table({placeOfOrigin}, {placeOfDestination}, 'VariableNames', {'PlaceOfOrigin', 'PlaceOfDestination'});
        
            % Append new data if it's not already present
            if isempty(existingTable) || ~ismember(newData, existingTable, 'rows')
                allData = [existingTable; newData];
                % Write the updated data back to the Excel file
                writetable(allData, excelFile, 'Sheet', sheetName, 'WriteMode', 'overwrite');
            end
        
            % Optionally, create an instance of the new app
            destinationApp = Flight_DestinationDate;
            % Set the properties or fields in the new app
            destinationApp.PlaceOfOrigin = placeOfOrigin;
            destinationApp.PlaceOfDestination = placeOfDestination;
            % Open the new app
            destinationApp.UIFigure.Visible = 'on';
            % Close the current app
            delete(app.UIFigure);
           
        end

        % Callback function: Hyperlink_3
        function CEB_MNL(app, event)
            % Data to be transferred
            placeOfOrigin = app.CEBUCEBLabel.Text;
            placeOfDestination = app.MANILAMNLLabel_2.Text;
        
            % Check if Excel file exists and read existing data
            excelFile = 'passengerDetails.xlsx';
            sheetName = 'Sheet1';
            
            if isfile(excelFile)
                % Read existing data from the Excel file
                [~, ~, existingData] = xlsread(excelFile, sheetName);
                % Convert existing data to a table
                if isempty(existingData)
                    existingTable = table();
                else
                    existingTable = cell2table(existingData(2:end,:), 'VariableNames', existingData(1,:));
                end
            else
                existingTable = table();
            end
        
            % Create new data table
            newData = table({placeOfOrigin}, {placeOfDestination}, 'VariableNames', {'PlaceOfOrigin', 'PlaceOfDestination'});
        
            % Append new data if it's not already present
            if isempty(existingTable) || ~ismember(newData, existingTable, 'rows')
                allData = [existingTable; newData];
                % Write the updated data back to the Excel file
                writetable(allData, excelFile, 'Sheet', sheetName, 'WriteMode', 'overwrite');
            end
        
            % Optionally, create an instance of the new app
            destinationApp = Flight_DestinationDate;
            % Set the properties or fields in the new app
            destinationApp.PlaceOfOrigin = placeOfOrigin;
            destinationApp.PlaceOfDestination = placeOfDestination;
            % Open the new app
            destinationApp.UIFigure.Visible = 'on';
            % Close the current app
            delete(app.UIFigure);
        end

        % Callback function: Hyperlink_4
        function Other_Flights(app, event)
            % Create an instance of the new app
            destPanelApp = Flight_FlightInformation;
            
            % Make the new app visible
            destPanelApp.UIFigure.Visible = 'on';
               
            delete(app.UIFigure);
        end

        % Image clicked function: Image5
        function Go_To_Profile(app, event)
            Flight_Profile;
           
            delete(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'Est._2024-removebg-preview.png');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'b9736d91806e3a2b8db1ab3e6a7bb0b0 (1).jpg');
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, '88b9cea9f2d6fc48920f86cf8033620e.jpg');
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Est._2024-removebg-preview (1).png');
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = fullfile(pathToMLAPP, '3995e1196e99d10da48bb5e6a5ac8cac.jpg');
            app.Image5 = uiimage(app.UIFigure);
            app.Image5.ImageClickedFcn = createCallbackFcn(app, @Go_To_Profile, true);
            app.Image5.Tooltip = {'Click To See Account Profile'};
            app.Image5.ImageSource = fullfile(pathToMLAPP, '5700c04197ee9a4372a35ef16eb78f4e-removebg-preview.png');
            app.Image6 = uiimage(app.UIFigure);
            app.Image6.ImageSource = fullfile(pathToMLAPP, '702e40edcf6d527a7cb598ba2204d6be.jpg');
            app.FEATUREDFLIGHTSLabel = uilabel(app.UIFigure);
            app.FEATUREDFLIGHTSLabel.Text = 'FEATURED FLIGHTS';
            app.Image7 = uiimage(app.UIFigure);
            app.Image7.ImageSource = fullfile(pathToMLAPP, 'aa_aviationgroup_FA (1).png');
            app.Image6_4 = uiimage(app.UIFigure);
            app.Image6_4.ImageSource = fullfile(pathToMLAPP, '702e40edcf6d527a7cb598ba2204d6be.jpg');
            app.BESMINAIRLINESLabel = uilabel(app.UIFigure);
            app.BESMINAIRLINESLabel.Text = 'BESMIN AIRLINES';
            app.wherebestluxuriesarebareminimumLabel = uilabel(app.UIFigure);
            app.wherebestluxuriesarebareminimumLabel.Text = 'where best luxuries are bare minimum';
            app.Image6_3 = uiimage(app.UIFigure);
            app.Image6_3.ImageSource = fullfile(pathToMLAPP, '702e40edcf6d527a7cb598ba2204d6be.jpg');
            app.Hyperlink = uihyperlink(app.UIFigure);
            app.Hyperlink.HyperlinkClickedFcn = createCallbackFcn(app, @MNL_DVO_BOOK, true);
            app.Hyperlink.Text = {'BOOK'; ''};
            app.MANILAMNLLabel = uilabel(app.UIFigure);
            app.MANILAMNLLabel.Text = 'MANILA , MNL';
            app.Image6_2 = uiimage(app.UIFigure);
            app.Image6_2.ImageSource = fullfile(pathToMLAPP, '702e40edcf6d527a7cb598ba2204d6be.jpg');
            app.DAVAODVOLabel = uilabel(app.UIFigure);
            app.DAVAODVOLabel.Text = 'DAVAO, DVO';
            app.BOARDINGTIME1800Label = uilabel(app.UIFigure);
            app.BOARDINGTIME1800Label.Text = 'BOARDING TIME : 18:00';
            app.Image7_2 = uiimage(app.UIFigure);
            app.Image7_2.ImageSource = fullfile(pathToMLAPP, 'Philippine-Airlines-Logo (1).png');
            app.Hyperlink_2 = uihyperlink(app.UIFigure);
            app.Hyperlink_2.HyperlinkClickedFcn = createCallbackFcn(app, @RXS_DVO_BOOK, true);
            app.Hyperlink_2.Text = {'BOOK'; ''};
            app.ROXASRXSLabel = uilabel(app.UIFigure);
            app.ROXASRXSLabel.Text = 'ROXAS, RXS';
            app.DAVAODVOLabel_2 = uilabel(app.UIFigure);
            app.DAVAODVOLabel_2.Text = 'DAVAO, DVO';
            app.BOARDINGTIME1300Label = uilabel(app.UIFigure);
            app.BOARDINGTIME1300Label.Text = 'BOARDING TIME : 13:00';
            app.Image7_3 = uiimage(app.UIFigure);
            app.Image7_3.ImageSource = fullfile(pathToMLAPP, 'Cebu-Pacific-Logo (1).png');
            app.Hyperlink_3 = uihyperlink(app.UIFigure);
            app.Hyperlink_3.HyperlinkClickedFcn = createCallbackFcn(app, @CEB_MNL, true);
            app.Hyperlink_3.Text = {'BOOK'; ''};
            app.CEBUCEBLabel = uilabel(app.UIFigure);
            app.CEBUCEBLabel.Text = 'CEBU, CEB';
            app.Image4_4 = uiimage(app.UIFigure);
            app.Image4_4.ImageSource = fullfile(pathToMLAPP, '3995e1196e99d10da48bb5e6a5ac8cac.jpg');
            app.MANILAMNLLabel_2 = uilabel(app.UIFigure);
            app.MANILAMNLLabel_2.Text = 'MANILA, MNL';
            app.BOARDINGTIME1800Label_3 = uilabel(app.UIFigure);
            app.BOARDINGTIME1800Label_3.Text = 'BOARDING TIME : 18:00';
            app.Hyperlink_4 = uihyperlink(app.UIFigure);
            app.Hyperlink_4.HyperlinkClickedFcn = createCallbackFcn(app, @Other_Flights, true);
            app.Hyperlink_4.Text = {'BOOK'; ''};
            app.LOOKFORMOREFLIGHTCLICKBELOWTOSEARCHFORMOREFLIGHTLabel = uilabel(app.UIFigure);
            app.LOOKFORMOREFLIGHTCLICKBELOWTOSEARCHFORMOREFLIGHTLabel.Text = 'LOOK FOR MORE FLIGHT?  CLICK BELOW TO SEARCH FOR MORE FLIGHT ';
            app.Image4_5 = uiimage(app.UIFigure);
            app.Image4_5.ImageSource = fullfile(pathToMLAPP, '3995e1196e99d10da48bb5e6a5ac8cac.jpg');
            app.Image4_6 = uiimage(app.UIFigure);
            app.Image4_6.ImageSource = fullfile(pathToMLAPP, '3995e1196e99d10da48bb5e6a5ac8cac.jpg');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Flight_Webpage_Front

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end