classdef Passenger_Health < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Image7                      matlab.ui.control.Image
        Image5                      matlab.ui.control.Image
        Image2                      matlab.ui.control.Image
        SUBMITButton                matlab.ui.control.Button
        NoCheckBox_5                matlab.ui.control.CheckBox
        YesCheckBox_5               matlab.ui.control.CheckBox
        Label                       matlab.ui.control.Label
        HaveyouorderedaSPMLSpecialMealLabel  matlab.ui.control.Label
        NoCheckBox_4                matlab.ui.control.CheckBox
        YesCheckBox_4               matlab.ui.control.CheckBox
        NoCheckBox_3                matlab.ui.control.CheckBox
        YesCheckBox_3               matlab.ui.control.CheckBox
        NoCheckBox_2                matlab.ui.control.CheckBox
        YesCheckBox_2               matlab.ui.control.CheckBox
        AnnouncementrequiredHyperSevereAirborneLabel  matlab.ui.control.Label
        WillyoubetravellingwithsomeoneelseLabel  matlab.ui.control.Label
        WillyoubetravellingwithanEpipenLabel  matlab.ui.control.Label
        SpecifyotherprecautionsifanyLabel  matlab.ui.control.Label
        NoCheckBox                  matlab.ui.control.CheckBox
        YesCheckBox                 matlab.ui.control.CheckBox
        EditField_2                 matlab.ui.control.EditField
        EditField                   matlab.ui.control.EditField
        OtherAllergiesLabel         matlab.ui.control.Label
        FoodFluidsLabel             matlab.ui.control.Label
        AllergyHistoryLabel         matlab.ui.control.Label
        DoyouhaveanyallergiesLabel  matlab.ui.control.Label
        PASSENGERNUMBER1AllergyInformationLabel  matlab.ui.control.Label
        Image6                      matlab.ui.control.Image
        Image_2                     matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SUBMITButton
        function SUBMITButtonPushed(app, event)
            % Collect data from UI components and ensure proper data types
            data.allergy_yes = app.YesCheckBox.Value;
            data.allergy_no = app.NoCheckBox.Value;
            data.food_fluids = string(app.EditField.Value); % Convert to string
            data.other_allergies = string(app.EditField_2.Value); % Convert to string
            data.epipen = app.YesCheckBox_2.Value;
            data.traveling_with_someone = app.YesCheckBox_3.Value;
            data.special_meal = app.YesCheckBox_5.Value;
            data.announcement = app.YesCheckBox_4.Value;
            
            % Create a table with the specified columns
            newEntry = table(data.allergy_yes, data.allergy_no, data.food_fluids, data.other_allergies, ...
                             data.epipen, data.traveling_with_someone, data.special_meal, ...
                             data.announcement, 'VariableNames', { ...
                             'AllergyYes', 'AllergyNo', 'FoodFluids', 'OtherAllergies', ...
                             'Epipen', 'TravelingWithSomeone', 'SpecialMeal', 'Announcement'});
            
            % Define file path and sheet name
            healthInfoFile = 'PassengerHealthInformation.xlsx'; % Excel file to store data
            sheetName = 'Sheet1'; % Define the sheet name
            
            % Check if the file exists
            if isfile(healthInfoFile)
                % Append data to existing file
                try
                    % Read the existing data
                    existingData = readtable(healthInfoFile, 'Sheet', sheetName);
                    % Append new data
                    updatedData = [existingData; newEntry];
                    % Write the updated data back to the file
                    writetable(updatedData, healthInfoFile, 'Sheet', sheetName);
                catch ME
                    uialert(app.UIFigure, ['An error occurred while updating the file: ', ME.message], 'Error');
                end
            else
                % Create new file and write data
                try
                    writetable(newEntry, healthInfoFile, 'Sheet', sheetName);
                catch ME
                    uialert(app.UIFigure, ['An error occurred while creating the file: ', ME.message], 'Error');
                end
            end
            
            % Notify user of successful data saving
            uialert(app.UIFigure, 'Data saved successfully!', 'Success');
             % Close the UI figure if needed
             Flight_Emergency;
             delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'b9736d91806e3a2b8db1ab3e6a7bb0b0 (1).jpg');
            app.Image6 = uiimage(app.UIFigure);
            app.Image6.ImageSource = fullfile(pathToMLAPP, '702e40edcf6d527a7cb598ba2204d6be.jpg');
            app.PASSENGERNUMBER1AllergyInformationLabel = uilabel(app.UIFigure);
            app.PASSENGERNUMBER1AllergyInformationLabel.Text = 'PASSENGER NUMBER 1 (Allergy Information)';
            app.DoyouhaveanyallergiesLabel = uilabel(app.UIFigure);
            app.DoyouhaveanyallergiesLabel.Text = 'Do you have any allergies?';
            app.AllergyHistoryLabel = uilabel(app.UIFigure);
            app.AllergyHistoryLabel.Text = 'Allergy History';
            app.FoodFluidsLabel = uilabel(app.UIFigure);
            app.FoodFluidsLabel.Text = 'Food & Fluids';
            app.OtherAllergiesLabel = uilabel(app.UIFigure);
            app.OtherAllergiesLabel.Text = 'Other Allergies';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.YesCheckBox = uicheckbox(app.UIFigure);
            app.YesCheckBox.Text = 'Yes';
            app.NoCheckBox = uicheckbox(app.UIFigure);
            app.NoCheckBox.Text = 'No';
            app.SpecifyotherprecautionsifanyLabel = uilabel(app.UIFigure);
            app.SpecifyotherprecautionsifanyLabel.Text = 'Specify other precautions  (if any):';
            app.WillyoubetravellingwithanEpipenLabel = uilabel(app.UIFigure);
            app.WillyoubetravellingwithanEpipenLabel.Text = 'Will you be travelling with an Epi-pen?';
            app.WillyoubetravellingwithsomeoneelseLabel = uilabel(app.UIFigure);
            app.WillyoubetravellingwithsomeoneelseLabel.Text = 'Will you be travelling with someone else?';
            app.AnnouncementrequiredHyperSevereAirborneLabel = uilabel(app.UIFigure);
            app.AnnouncementrequiredHyperSevereAirborneLabel.Text = 'Announcement required  (Hyper/Severe/Airborne)?';
            app.YesCheckBox_2 = uicheckbox(app.UIFigure);
            app.YesCheckBox_2.Text = 'Yes';
            app.NoCheckBox_2 = uicheckbox(app.UIFigure);
            app.NoCheckBox_2.Text = 'No';
            app.YesCheckBox_3 = uicheckbox(app.UIFigure);
            app.YesCheckBox_3.Text = 'Yes';
            app.NoCheckBox_3 = uicheckbox(app.UIFigure);
            app.NoCheckBox_3.Text = 'No';
            app.YesCheckBox_4 = uicheckbox(app.UIFigure);
            app.YesCheckBox_4.Text = 'Yes';
            app.NoCheckBox_4 = uicheckbox(app.UIFigure);
            app.NoCheckBox_4.Text = 'No';
            app.HaveyouorderedaSPMLSpecialMealLabel = uilabel(app.UIFigure);
            app.HaveyouorderedaSPMLSpecialMealLabel.Text = 'Have you ordered a SPML (Special Meal)?';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Note: Please contact our Reservations Office, if you want a special meal.';
            app.YesCheckBox_5 = uicheckbox(app.UIFigure);
            app.YesCheckBox_5.Text = 'Yes';
            app.NoCheckBox_5 = uicheckbox(app.UIFigure);
            app.NoCheckBox_5.Text = 'No';
            app.SUBMITButton = uibutton(app.UIFigure, 'push');
            app.SUBMITButton.ButtonPushedFcn = createCallbackFcn(app, @SUBMITButtonPushed, true);
            app.SUBMITButton.Text = 'SUBMIT';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, '88b9cea9f2d6fc48920f86cf8033620e.jpg');
            app.Image5 = uiimage(app.UIFigure);
            app.Image5.ImageSource = fullfile(pathToMLAPP, '5700c04197ee9a4372a35ef16eb78f4e-removebg-preview.png');
            app.Image7 = uiimage(app.UIFigure);
            app.Image7.ImageSource = fullfile(pathToMLAPP, 'Est._2024-removebg-preview (1).png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Passenger_Health

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end